classdef EHRA_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputFileMenu                   matlab.ui.container.Menu
        HazardInformationMenu           matlab.ui.container.Menu
        ComponentFragilityInformationMenu  matlab.ui.container.Menu
        SystemEquationInformationMenu   matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        IntensityInitialMenu            matlab.ui.container.Menu
        IntensityLastMenu               matlab.ui.container.Menu
        IntensitySubdivisionMenu        matlab.ui.container.Menu
        NumberofSamplesforEachIntensityMenu  matlab.ui.container.Menu
        SolverMenu                      matlab.ui.container.Menu
        BooleanAlgebraUMBMenu           matlab.ui.container.Menu
        OrginalDQFMMenu                 matlab.ui.container.Menu
        ImprovedDQFMMenu                matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        HazardCurvesMenu                matlab.ui.container.Menu
        ComponentFragilityCurvesMenu    matlab.ui.container.Menu
        SystemFragilityCurvesMenu       matlab.ui.container.Menu
        SystemRiskResultBarMenu         matlab.ui.container.Menu
        SystemRiskResultTableMenu       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HazardFragilityInformationLabel  matlab.ui.control.Label
        ExternalHazardInformationLabel  matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        FragilityInputXLSButton         matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        HazardInputXLSButton            matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        HazardCurvesTab                 matlab.ui.container.Tab
        ExternalHazardCurvesLabel       matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        ComponentFragilityCurvesTab     matlab.ui.container.Tab
        ComponentExternalFragilityCurvesforSSCsLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        SystemFragilityResultsTab       matlab.ui.container.Tab
        SystemFragilityResultsLabel     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        SystemRiskResultBarTab          matlab.ui.container.Tab
        SystemRiskResultBarLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        SystemRiskResultTable           matlab.ui.container.Tab
        SystemRiskResultTableLabel      matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        SolverPanel                     matlab.ui.container.Panel
        BooleanAlgebraUMBButton         matlab.ui.control.Button
        ImprovedDQFMScalingButton       matlab.ui.control.Button
        OriginalDQFMButton              matlab.ui.control.Button
        IntensityInitalEditField        matlab.ui.control.NumericEditField
        IntensityInitalEditFieldLabel   matlab.ui.control.Label
        ofSamplesforEachIntensityEditField  matlab.ui.control.NumericEditField
        ofSamplesforEachIntensityEditFieldLabel  matlab.ui.control.Label
        IntensitySubdivisionEditField   matlab.ui.control.NumericEditField
        IntensitySubdivisionEditFieldLabel  matlab.ui.control.Label
        IntensityLastEditField          matlab.ui.control.NumericEditField
        IntensityLastEditFieldLabel     matlab.ui.control.Label
        RiskQuantificationLabel         matlab.ui.control.Label
        SystemsAnalysisModelLabel       matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        SystemEquationXLSButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        PGA_I % PGA Initial
        PGA_L % PGA Last
        PGA_S % PGA Subdivision
        NSEP  % Number of Samples for each PGA

        PGA   % PGA vector
        PF_C  % Component Fragilities
        PF_S  % System Fragilities 
        Risk  % System Risk
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PGA_I = app.IntensityInitalEditField.Value;
            app.PGA_L = app.IntensityLastEditField.Value;
            app.PGA_S = app.IntensitySubdivisionEditField.Value;
            app.NSEP = app.ofSamplesforEachIntensityEditField.Value;
        end

        % Callback function: HazardInformationMenu, HazardInputXLSButton
        function HazardInputXLSButtonPushed(app, event)
            [filename,path]=uigetfile();
            addpath(path)
            t1 =  readtable(filename,'Sheet',1);
            app.UITable.Data = t1;
        end

        % Callback function: ComponentFragilityInformationMenu, 
        % ...and 1 other component
        function FragilityInputXLSButtonPushed(app, event)
           [filename,path]=uigetfile();
           addpath(path)
           t2 =  readtable(filename,'Sheet',2);
           app.UITable2.Data = t2;
        end

        % Callback function: SystemEquationInformationMenu, 
        % ...and 1 other component
        function SytemEquationXLSButtonPushed(app, event)
           [filename,path]=uigetfile();
           addpath(path)
           t3 = readtable(filename,'Sheet',3); 
           app.UITable3.Data = t3;
        end

        % Callback function: OrginalDQFMMenu, OriginalDQFMButton
        function OriginalDQFMButtonPushed(app, event)
            t1 = app.UITable.Data;
            t2 = app.UITable2.Data;
            t3 = app.UITable3.Data;

            PGA = [app.PGA_I:app.PGA_S:app.PGA_L]';
            n_var = sum(t2{:,end} == 0);
            n_rf  = length(t2{:,end})-n_var;
            Am = t2{1:n_var,2};
            br = t2{1:n_var,3};
            bu = t2{1:n_var,4};
            rf = t2{n_var+1:end,5};
            bc = (br.^2+bu.^2).^(1/2);
            ns = size(t3,1);

            tic
            % Decompose bc to bRc and bCc
            bRc = (bc.^2/2).^(1/2);
            bCc = (bc.^2/2).^(1/2);

            % Define correlation
%           rho = 0;  % 0.7; 0.99999; 0;
            
            % Extract samples using MCS
            N = app.NSEP; %1e3;

            for j = 1:length(PGA) 
                j
  
                % Response
                Rm = Am;
                Rb = bRc;
                % n_var  = length(Rm);
                Rcov = eye(n_var);
                RSamples = mvnrnd(zeros(n_var,1),Rcov,N);
                for i = 1:n_var
                    RSamples(:,i) = (PGA(j)/Rm(i)*Rm(i))*exp(RSamples(:,i)*bRc(i));
                end

                % Capacity
                Cm = Am;
                Cb = bCc;
                n_var  = length(Cm);
                Ccov = eye(n_var);
                [CSamples] = mvnrnd(zeros(n_var,1),Ccov,N);
                for i = 1:n_var
                    CSamples(:,i) = Cm(i)*exp(CSamples(:,i)*bCc(i));
                end

                % -- Evaluate wheter component failure occurs or not --
                for i = 1:n_var
                    C(:,i) = RSamples(:,i) > CSamples(:,i);
                end

                % --- Evaluate wheter component failure occurs or not according to Random Failure
                for i = 1:n_rf
                    for k = 1:N
                        RF(k,i) = binornd(1,rf(i));   % Extract '0'(safe) or '1'(failure) samples using Binomial distribution 
                    end
                end
                
                % -- Systems Analysis -- 
                % System Define by using Logical expression (basically "or" "and")
%                 eval(sprintf('C(:,%d)', 1))  % -> C(:,i)
                for i = 1:ns      
                    S(:,i) = eval(char(t3{i,2}));
                end

                % -- Evaluate Failure Probability for Systems -- 
                for i = 1:n_var
                    pfC(j,i) = sum(C(:,i))/N; %length(find (RSamples(:,1) > CSamples(:,1)))/N;
                end               
    
                % Define System : 
                for i = 1:ns
                    pfS(j,i) = sum(S(:,i))/N;
                end
            end
            toc
            H = t1{:,:};
            Ht = [H; (H(end,1)+0.1) 0; (H(end,1)+1.1) 0; (H(end,1)+2.1) 0; (H(end,1)+10) 0; (H(end,1)+100) 0; (H(end,1)+200) 0;];
            Htrc = interp1(log(Ht(:,1)),log(Ht(:,2)),log(PGA),'linear','extrap'); Htrc = exp(Htrc);
            for i=1:ns
                R(i,:) = RISK(PGA,pfS(:,i),Htrc);
            end
            
            app.PGA   = PGA;
            app.PF_C = pfC;   % Component Fragilities
            app.PF_S = pfS;   % System Fragilities 
            app.Risk = R;     % System Risk
            
            % Figure : System Seismic Fragility Curves
            plot(app.UIAxes,PGA,pfS);legend(app.UIAxes,t3{:,1}');

            % Figure : Seismic Hazard curves
            Hexp = interp1(log(H(:,1)),log(H(:,2)),log(PGA),'linear','extrap'); Hexp = exp(Hexp); 
            loglog(app.UIAxes3,H(:,1),H(:,2),'ro',PGA,Htrc,'b:',PGA,Hexp,'k--','LineWidth',2); legend(app.UIAxes3,'data','Truncated','Extrapolated');

            % Figure : Seismic Fragility curves
            SSCnum = [1:length(Am)];
            SSCcel = num2cell(SSCnum);
            for i=1:length(SSCcel)
                SSCcel{i} = "C" + num2str(SSCcel{i});
            end
            plot(app.UIAxes4,PGA,pfC);legend(app.UIAxes4,SSCcel);
           
            % Table : System Risk Table
            SRT = t3{:,1};
            SET = table2array(t3(:,4)); % Secondary ET

            for i=1:length(R)
                SRT{i,2} = R(i);
                SRT{i,3} = R(i)*SET(i);
                % CDFc(i,:) =  R(i)*SET(i);
            end
            
            format shortEng
            format compact
            app.UITable4.Data = cell2table(SRT);

            % Figure : System Seismic Risk Curve
            test = t3{:,1}';
            X = categorical(test);
            X = reordercats(X,test);
            bar(app.UIAxes2,X,[R]);
            text(app.UIAxes2,1:length([R]),[R],num2str([R]),'vert','bottom','horiz','center');

            function [Riskn] = RISK(pga,F,H)
                % Number of data points
                n = length(pga);
                % Combine (Integrated by Trapezoidal rule)
                IP = 0; h = 1e-3;
                % Use forward for first point
                INT1 = F(1)*abs((interp1(pga,H,pga(1)+h)-interp1(pga,H,pga(1)))/(h));
                INT2 = F(2)*abs((interp1(pga,H,pga(2)+h)-interp1(pga,H,pga(2)))/(h));
                dpga = pga(2)-pga(1);
                IP = IP +dpga*(INT1+INT2)/2;
                % Use central difference method for mid-points
                for j =2:n-2
                    INT1 = F(j)*abs((interp1(pga,H,pga(j)+h)-interp1(pga,H,pga(j)-h))/(2*h));
                    INT2 = F(j+1)*abs((interp1(pga,H,pga(j+1)+h)-interp1(pga,H,pga(j+1)-h))/(2*h));
                    dpga = pga(j+1)-pga(j);
                    IP = IP +dpga*(INT1+INT2)/2;
                end
                % Use backward for last point
                INT1 = F(n-1)*abs((interp1(pga,H,pga(n-1))-interp1(pga,H,pga(n-1)-h))/(h));
                INT2 = F(n)*abs((interp1(pga,H,pga(n))-interp1(pga,H,pga(n)-h))/(h));
                dpga = pga(n)-pga(n-1);
                IP = IP +dpga*(INT1+INT2)/2;

                Riskn = IP;
            end
        end

        % Callback function: IntensityInitalEditField, IntensityInitialMenu
        function PGAInitalEditFieldValueChanged(app, event)
            app.PGA_I = app.IntensityInitalEditField.Value;
        end

        % Callback function: IntensityLastEditField, IntensityLastMenu
        function PGALastEditFieldValueChanged(app, event)
            app.PGA_L = app.IntensityLastEditField.Value;
        end

        % Callback function: IntensitySubdivisionEditField, 
        % ...and 1 other component
        function PGASubdivisionEditFieldValueChanged(app, event)
            app.PGA_S = app.IntensitySubdivisionEditField.Value;
        end

        % Callback function: NumberofSamplesforEachIntensityMenu, 
        % ...and 1 other component
        function ofSamplesforEachPGAEditFieldValueChanged(app, event)
            app.NSEP = app.ofSamplesforEachIntensityEditField.Value;
        end

        % Callback function: SystemFragilityCurvesMenu, UIAxes
        function UIAxesButtonDown(app, event)
            PGA = app.PGA;
            pfS = app.PF_S;
            plot(app.UIAxes,PGA,pfS);legend(app.UIAxes,t3{:,1}');
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
            t3 = app.UITable3.Data;
            R = app.Risk;
            X = categorical(t3{:,1}');
            X = reordercats(X,t3{:,1}');
            bar(app.UIAxes2,X,R);
            text(app.UIAxes2,1:length(R),R,num2str(R),'vert','bottom','horiz','center'); 
%             box off;
        end

        % Callback function: ImprovedDQFMMenu, ImprovedDQFMScalingButton
        function ImprovedDQFMScalingButtonPushed(app, event)
            t1 = app.UITable.Data;
            t2 = app.UITable2.Data;
            t3 = app.UITable3.Data;

            PGA = [app.PGA_I:app.PGA_S:app.PGA_L]';
            n_var = sum(t2{:,end} == 0);
            n_rf  = length(t2{:,end})-n_var;
            Am = t2{1:n_var,2};
            br = t2{1:n_var,3};
            bu = t2{1:n_var,4};
            rf = t2{n_var+1:end,5};
            bc = (br.^2+bu.^2).^(1/2);
            ns = size(t3,1);
            
            tic
            % Decompose bc to bRc and bCc
            bRc = (bc.^2/2).^(1/2);
            bCc = (bc.^2/2).^(1/2);

            % Define correlation
%           rho = 0;  % 0.7; 0.99999; 0;
            
            % Extract samples using MCS
            N = app.NSEP; %1e3;
            
            % Response
            Rm = Am;
            % n_var  = length(Rm);
            Rcov = eye(n_var);

            RSamples_org = mvnrnd(zeros(n_var,1),Rcov,N);
            for i = 1:n_var
                RSamples_org(:,i) = Rm(i)*exp(RSamples_org(:,i)*bRc(i));
            end

            % Capacity
            Cm = Am;
            Ccov = eye(n_var);
            [CSamples] = mvnrnd(zeros(n_var,1),Ccov,N);
            for i = 1:n_var
                CSamples(:,i) = Cm(i)*exp(CSamples(:,i)*bCc(i));
            end
            
            % --- Evaluate wheter component failure occurs or not according to Random Failure
            for i = 1:n_rf
                for k = 1:N
                    RF(k,i) = binornd(1,rf(i));   % Extract '0'(safe) or '1'(failure) samples using Binomial distribution 
                end
            end


            for j = 1:length(PGA) 
                j
  
                % -- Scale Response under linear assumption --
                for i = 1:n_var
                    RSamples(:,i) = RSamples_org(:,i).*(PGA(j)/Am(i)); % Scale according to seismic intensity
                end
                
                % -- Evaluate wheter component failure occurs or not --
                for i = 1:n_var
                    C(:,i) = RSamples(:,i) > CSamples(:,i);
                end
                
                % -- Systems Analysis -- 
                % System Define by using Logical expression (basically "or" "and")
%                 eval(sprintf('C(:,%d)', 1))  % -> C(:,i)
                for i = 1:ns      
                    S(:,i) = eval(char(t3{i,2}));
                end

                % -- Evaluate Failure Probability for Systems -- 
                for i = 1:n_var
                    pfC(j,i) = sum(C(:,i))/N; %length(find (RSamples(:,1) > CSamples(:,1)))/N;
                end               
    
                % Define System : 
                for i = 1:ns
                    pfS(j,i) = sum(S(:,i))/N;
                end
            end
            toc
            H = t1{:,:};
            Ht = [H; (H(end,1)+0.1) 0; (H(end,1)+1.1) 0; (H(end,1)+2.1) 0; (H(end,1)+10) 0; (H(end,1)+100) 0; (H(end,1)+200) 0;];
            Htrc = interp1(log(Ht(:,1)),log(Ht(:,2)),log(PGA),'linear','extrap'); Htrc = exp(Htrc);
            for i=1:ns
                R(i,:) = RISK(PGA,pfS(:,i),Htrc);
            end
            
            app.PGA   = PGA;
            app.PF_C = pfC;   % Component Fragilities
            app.PF_S = pfS;   % System Fragilities 
            app.Risk = R;     % System Risk
            
            % Figure : System Seismic Fragility Curve
            plot(app.UIAxes,PGA,pfS);legend(app.UIAxes,t3{:,1}');

            % Figure : Seismic Hazard curves
            Hexp = interp1(log(H(:,1)),log(H(:,2)),log(PGA),'linear','extrap'); Hexp = exp(Hexp); 
            loglog(app.UIAxes3,H(:,1),H(:,2),'ro',PGA,Htrc,'b:',PGA,Hexp,'k--','LineWidth',2); legend(app.UIAxes3,'data','Truncated','Extrapolated');

            % Figure : Seismic Fragility curves
            SSCnum = [1:length(Am)];
            SSCcel = num2cell(SSCnum);
            for i=1:length(SSCcel)
                SSCcel{i} = "C" + num2str(SSCcel{i});
            end

            plot(app.UIAxes4,PGA,pfC);legend(app.UIAxes4,SSCcel);

            % Table : System Risk Table
            SRT = t3{:,1};
            SET = table2array(t3(:,4)); % Secondary ET

            for i=1:length(R)
                SRT{i,2} = R(i);
                SRT{i,3} = R(i)*SET(i);
                % CDFc(i,:) =  R(i)*SET(i);
            end

            format shortEng
            format compact
            app.UITable4.Data = cell2table(SRT);

            % Figure : System Seismic Risk Curve
            test = t3{:,1}';
            X = categorical(test);
            X = reordercats(X,test);
            bar(app.UIAxes2,X,[R]);
            text(app.UIAxes2,1:length([R]),[R],num2str([R]),'vert','bottom','horiz','center');

            function [Riskn] = RISK(pga,F,H)
                % Number of data points
                n = length(pga);
                % Combine (Integrated by Trapezoidal rule)
                IP = 0; h = 1e-3;
                % Use forward for first point
                INT1 = F(1)*abs((interp1(pga,H,pga(1)+h)-interp1(pga,H,pga(1)))/(h));
                INT2 = F(2)*abs((interp1(pga,H,pga(2)+h)-interp1(pga,H,pga(2)))/(h));
                dpga = pga(2)-pga(1);
                IP = IP +dpga*(INT1+INT2)/2;
                % Use central difference method for mid-points
                for j =2:n-2
                    INT1 = F(j)*abs((interp1(pga,H,pga(j)+h)-interp1(pga,H,pga(j)-h))/(2*h));
                    INT2 = F(j+1)*abs((interp1(pga,H,pga(j+1)+h)-interp1(pga,H,pga(j+1)-h))/(2*h));
                    dpga = pga(j+1)-pga(j);
                    IP = IP +dpga*(INT1+INT2)/2;
                end
                % Use backward for last point
                INT1 = F(n-1)*abs((interp1(pga,H,pga(n-1))-interp1(pga,H,pga(n-1)-h))/(h));
                INT2 = F(n)*abs((interp1(pga,H,pga(n))-interp1(pga,H,pga(n)-h))/(h));
                dpga = pga(n)-pga(n-1);
                IP = IP +dpga*(INT1+INT2)/2;

                Riskn = IP;
            end

        end

        % Callback function: HazardCurvesMenu, UIAxes3
        function UIAxes3ButtonDown(app, event)
            PGA = app.PGA;    
            H = table2array(app.UITable.Data);
            Ht = [H; H(end,1)+0.1 0; H(end,1)+1*PGA(end) 0; H(end,1)+2*PGA(end) 0];
            Htrc = interp1(log(Ht(:,1)),log(Ht(:,2)),log(PGA),'linear','extrap'); Htrc = exp(Htrc);
            Hexp = interp1(log(H(:,1)),log(H(:,2)),log(PGA),'linear','extrap'); Hexp = exp(Hexp); 
            loglog(app.UIAxes3,H(:,1),H(:,2),'ro',PGA,Htrc,'b:',PGA,Hexp,'k--','LineWidth',2); legend('data','Truncated','Extrapolated');
        end

        % Callback function: ComponentFragilityCurvesMenu, UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Callback function: SystemRiskResultTableMenu, UITable4
        function UITable4CellEdit(app, event)

            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Callback function: BooleanAlgebraUMBButton, BooleanAlgebraUMBMenu
        function BooleanAlgebraUMBButtonPushed(app, event)
            t1 = app.UITable.Data;
            t2 = app.UITable2.Data;
            t3 = app.UITable3.Data;

            PGA = [app.PGA_I:app.PGA_S:app.PGA_L]';
            n_var = sum(t2{:,end} == 0);
            n_rf  = length(t2{:,end})-n_var;
            Am = t2{1:n_var,2};
            br = t2{1:n_var,3};
            bu = t2{1:n_var,4};
            rf = t2{n_var+1:end,5};
            bc = (br.^2+bu.^2).^(1/2);
            ns = size(t3,1);

            % -- Evaluate wheter component failure occurs or not --
            for i = 1:n_var
                C(:,i) = normcdf(log(PGA),log(Am(i)), bc(i)); 
            end


            for i = 1:n_rf
                RF(:,i) = rf(i).*ones(length(PGA),1);
            end
            
            % -- Systems Analysis -- 
            % System Define by using Unimodal Bound Method (basically "or" "and")
%           eval(sprintf('C(:,%d)', 1))  % -> C(:,i)
            for i = 1:ns      
                S(:,i) = eval(char(t3{i,3}));
            end
                     
            % -- Evaluate Failure Probability for Systems --  
            pfC = C;  
            pfS = S; 

            H = t1{:,:};
            % Figure : Seismic Hazard curves
            Ht = [H; (H(end,1)+0.1) 0; (H(end,1)+1.1) 0; (H(end,1)+2.1) 0; (H(end,1)+10) 0; (H(end,1)+100) 0; (H(end,1)+200) 0;];
            % Ht = [H;1 0; 2 0; 3 0];
            Htrc = interp1(log(Ht(:,1)),log(Ht(:,2)),log(PGA),'linear','extrap'); Htrc = exp(Htrc);
            Hexp = interp1(log(H(:,1)),log(H(:,2)),log(PGA),'linear','extrap'); Hexp = exp(Hexp); 
            loglog(app.UIAxes3,H(:,1),H(:,2),'ro',PGA,Htrc,'b:',PGA,Hexp,'k--','LineWidth',2); legend(app.UIAxes3,'data','Truncated','Extrapolated');
            for i=1:ns
                % R(i,:) = RISK(PGA,pfS(:,i),Htrc);
                R(i,:) = RISK(PGA,pfS(:,i),Hexp);
            end

            app.PGA   = PGA;
            app.PF_C = pfC;   % Component Fragilities
            app.PF_S = pfS;   % System Fragilities 
            app.Risk = R;     % System Risk
            
            % Figure : System Seismic Fragility Curve
            plot(app.UIAxes,PGA,pfS,'LineWidth',2);legend(app.UIAxes,t3{:,1}');

            % Figure : Seismic Fragility curves
            SSCnum = [1:length(Am)];
            SSCcel = num2cell(SSCnum);
            for i=1:length(SSCcel)
                SSCcel{i} = "C" + num2str(SSCcel{i});
            end

            plot(app.UIAxes4,PGA,pfC,'LineWidth',2);legend(app.UIAxes4,SSCcel);

            % Table : System Risk Table
            SRT = t3{:,1};
            SET = table2array(t3(:,4)); % Secondary ET

            for i=1:length(R)
                SRT{i,2} = R(i);
                SRT{i,3} = R(i)*SET(i);
                % CDFc(i,:) =  R(i)*SET(i);
            end
            
            format shortEng
            format compact
            app.UITable4.Data = cell2table(SRT);


            % Figure : System Seismic Risk Curve
            test = t3{:,1}';
            X = categorical(test);
            X = reordercats(X,test);
            bar(app.UIAxes2,X,[R]);
            text(app.UIAxes2,1:length([R]),[R],num2str([R]),'vert','bottom','horiz','center');

            function [Riskn] = RISK(pga,F,H)
                % Number of data points
                n = length(pga);
                % Combine (Integrated by Trapezoidal rule)
                IP = 0; h = 1e-3;
                % Use forward for first point
                INT1 = F(1)*abs((interp1(pga,H,pga(1)+h)-interp1(pga,H,pga(1)))/(h));
                INT2 = F(2)*abs((interp1(pga,H,pga(2)+h)-interp1(pga,H,pga(2)))/(h));
                dpga = pga(2)-pga(1);
                IP = IP +dpga*(INT1+INT2)/2;
                % Use central difference method for mid-points
                for j =2:n-2
                    INT1 = F(j)*abs((interp1(pga,H,pga(j)+h)-interp1(pga,H,pga(j)-h))/(2*h));
                    INT2 = F(j+1)*abs((interp1(pga,H,pga(j+1)+h)-interp1(pga,H,pga(j+1)-h))/(2*h));
                    dpga = pga(j+1)-pga(j);
                    IP = IP +dpga*(INT1+INT2)/2;
                end
                % Use backward for last point
                INT1 = F(n-1)*abs((interp1(pga,H,pga(n-1))-interp1(pga,H,pga(n-1)-h))/(h));
                INT2 = F(n)*abs((interp1(pga,H,pga(n))-interp1(pga,H,pga(n)-h))/(h));
                dpga = pga(n)-pga(n-1);
                IP = IP +dpga*(INT1+INT2)/2;

                Riskn = IP;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {533, 533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x', 279};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.InputFileMenu = uimenu(app.UIFigure);
            app.InputFileMenu.Text = 'Input File';
            app.HazardInformationMenu = uimenu(app.InputFileMenu);
            app.HazardInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @HazardInputXLSButtonPushed, true);
            app.HazardInformationMenu.Text = 'Hazard Information';
            app.ComponentFragilityInformationMenu = uimenu(app.InputFileMenu);
            app.ComponentFragilityInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @FragilityInputXLSButtonPushed, true);
            app.ComponentFragilityInformationMenu.Text = 'Component Fragility Information';
            app.SystemEquationInformationMenu = uimenu(app.InputFileMenu);
            app.SystemEquationInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @SytemEquationXLSButtonPushed, true);
            app.SystemEquationInformationMenu.Text = 'System Equation Information';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.IntensityInitialMenu = uimenu(app.SettingsMenu);
            app.IntensityInitialMenu.MenuSelectedFcn = createCallbackFcn(app, @PGAInitalEditFieldValueChanged, true);
            app.IntensityInitialMenu.Text = 'Intensity Initial';
            app.IntensityLastMenu = uimenu(app.SettingsMenu);
            app.IntensityLastMenu.MenuSelectedFcn = createCallbackFcn(app, @PGALastEditFieldValueChanged, true);
            app.IntensityLastMenu.Text = 'Intensity Last';
            app.IntensitySubdivisionMenu = uimenu(app.SettingsMenu);
            app.IntensitySubdivisionMenu.MenuSelectedFcn = createCallbackFcn(app, @PGASubdivisionEditFieldValueChanged, true);
            app.IntensitySubdivisionMenu.Text = 'Intensity Subdivision';
            app.NumberofSamplesforEachIntensityMenu = uimenu(app.SettingsMenu);
            app.NumberofSamplesforEachIntensityMenu.MenuSelectedFcn = createCallbackFcn(app, @ofSamplesforEachPGAEditFieldValueChanged, true);
            app.NumberofSamplesforEachIntensityMenu.Text = 'Number of Samples for Each Intensity';
            app.SolverMenu = uimenu(app.SettingsMenu);
            app.SolverMenu.Text = 'Solver';
            app.BooleanAlgebraUMBMenu = uimenu(app.SolverMenu);
            app.BooleanAlgebraUMBMenu.MenuSelectedFcn = createCallbackFcn(app, @BooleanAlgebraUMBButtonPushed, true);
            app.BooleanAlgebraUMBMenu.Text = 'Boolean Algebra (UMB)';
            app.OrginalDQFMMenu = uimenu(app.SolverMenu);
            app.OrginalDQFMMenu.MenuSelectedFcn = createCallbackFcn(app, @OriginalDQFMButtonPushed, true);
            app.OrginalDQFMMenu.Text = 'Orginal DQFM';
            app.ImprovedDQFMMenu = uimenu(app.SolverMenu);
            app.ImprovedDQFMMenu.MenuSelectedFcn = createCallbackFcn(app, @ImprovedDQFMScalingButtonPushed, true);
            app.ImprovedDQFMMenu.Text = 'Improved DQFM';
            app.ResultsMenu = uimenu(app.UIFigure);
            app.ResultsMenu.Text = 'Results';
            app.HazardCurvesMenu = uimenu(app.ResultsMenu);
            app.HazardCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.HazardCurvesMenu.Text = 'Hazard Curves';
            app.ComponentFragilityCurvesMenu = uimenu(app.ResultsMenu);
            app.ComponentFragilityCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.ComponentFragilityCurvesMenu.Text = 'Component Fragility Curves';
            app.SystemFragilityCurvesMenu = uimenu(app.ResultsMenu);
            app.SystemFragilityCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SystemFragilityCurvesMenu.Text = 'System Fragility Curves';
            app.SystemRiskResultBarMenu = uimenu(app.ResultsMenu);
            app.SystemRiskResultBarMenu.Text = 'System Risk Result Bar';
            app.SystemRiskResultTableMenu = uimenu(app.ResultsMenu);
            app.SystemRiskResultTableMenu.MenuSelectedFcn = createCallbackFcn(app, @UITable4CellEdit, true);
            app.SystemRiskResultTableMenu.Text = 'System Risk Result Table';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Basic Input - 1';
            app.HazardInputXLSButton = uibutton(app.LeftPanel, 'push');
            app.HazardInputXLSButton.ButtonPushedFcn = createCallbackFcn(app, @HazardInputXLSButtonPushed, true);
            app.HazardInputXLSButton.Text = 'Hazard Input (XLS)';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Intensity'; 'AFE (/yr)'};
            app.UITable.RowName = {};
            app.FragilityInputXLSButton = uibutton(app.LeftPanel, 'push');
            app.FragilityInputXLSButton.ButtonPushedFcn = createCallbackFcn(app, @FragilityInputXLSButtonPushed, true);
            app.FragilityInputXLSButton.Text = 'Fragility Input (XLS)';
            app.UITable2 = uitable(app.LeftPanel);
            app.UITable2.ColumnName = {'Componenet'; 'Median'; 'Beta_r'; 'Beta_u'; 'RF'};
            app.UITable2.RowName = {};
            app.ExternalHazardInformationLabel = uilabel(app.LeftPanel);
            app.ExternalHazardInformationLabel.Text = '1. External Hazard Information';
            app.HazardFragilityInformationLabel = uilabel(app.LeftPanel);
            app.HazardFragilityInformationLabel.Text = '2. Hazard Fragility Information';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Output';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.HazardCurvesTab = uitab(app.TabGroup);
            app.HazardCurvesTab.Title = 'Hazard Curves';
            app.UIAxes3 = uiaxes(app.HazardCurvesTab);
            xlabel(app.UIAxes3, 'PGA (g)')
            ylabel(app.UIAxes3, 'Annual Frequency of Exceedance (/yr)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.ExternalHazardCurvesLabel = uilabel(app.HazardCurvesTab);
            app.ExternalHazardCurvesLabel.Text = '<External Hazard Curves>';
            app.ComponentFragilityCurvesTab = uitab(app.TabGroup);
            app.ComponentFragilityCurvesTab.Title = 'Component Fragility Curves';
            app.UIAxes4 = uiaxes(app.ComponentFragilityCurvesTab);
            xlabel(app.UIAxes4, 'PGA (g)')
            ylabel(app.UIAxes4, 'Probability of failures')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.ComponentExternalFragilityCurvesforSSCsLabel = uilabel(app.ComponentFragilityCurvesTab);
            app.ComponentExternalFragilityCurvesforSSCsLabel.Text = '<Component External Fragility Curves for SSCs>';
            app.SystemFragilityResultsTab = uitab(app.TabGroup);
            app.SystemFragilityResultsTab.Title = 'System Fragility Results';
            app.UIAxes = uiaxes(app.SystemFragilityResultsTab);
            xlabel(app.UIAxes, 'PGA (g)')
            ylabel(app.UIAxes, 'Probability of failures')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SystemFragilityResultsLabel = uilabel(app.SystemFragilityResultsTab);
            app.SystemFragilityResultsLabel.Text = '<System Fragility Results>';
            app.SystemRiskResultBarTab = uitab(app.TabGroup);
            app.SystemRiskResultBarTab.Title = 'System Risk Result Bar';
            app.UIAxes2 = uiaxes(app.SystemRiskResultBarTab);
            xlabel(app.UIAxes2, 'System Equations')
            ylabel(app.UIAxes2, 'Risk (/yr)')
            zlabel(app.UIAxes2, 'Z')
            app.SystemRiskResultBarLabel = uilabel(app.SystemRiskResultBarTab);
            app.SystemRiskResultBarLabel.Text = '<System Risk Result Bar>';
            app.SystemRiskResultTable = uitab(app.TabGroup);
            app.SystemRiskResultTable.Title = 'System Risk Result Table';
            app.UITable4 = uitable(app.SystemRiskResultTable);
            app.UITable4.ColumnName = {'Name'; 'Risk(/yr)'; 'CDF contribution(/yr)'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.SystemRiskResultTableLabel = uilabel(app.SystemRiskResultTable);
            app.SystemRiskResultTableLabel.Text = '<System Risk Result Table>';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Basic Input-2';
            app.SystemEquationXLSButton = uibutton(app.RightPanel, 'push');
            app.SystemEquationXLSButton.ButtonPushedFcn = createCallbackFcn(app, @SytemEquationXLSButtonPushed, true);
            app.SystemEquationXLSButton.Text = 'System Equation (XLS)';
            app.UITable3 = uitable(app.RightPanel);
            app.UITable3.ColumnName = {'Name'; 'System Equations(Logic)'; 'System Equations(UMB)'; 'Seconary ET'};
            app.UITable3.RowName = {};
            app.SystemsAnalysisModelLabel = uilabel(app.RightPanel);
            app.SystemsAnalysisModelLabel.Text = '3. Systems Analysis Model';
            app.RiskQuantificationLabel = uilabel(app.RightPanel);
            app.RiskQuantificationLabel.Text = '4. Risk Quantification';
            app.IntensityLastEditFieldLabel = uilabel(app.RightPanel);
            app.IntensityLastEditFieldLabel.Text = 'Intensity Last';
            app.IntensityLastEditField = uieditfield(app.RightPanel, 'numeric');
            app.IntensityLastEditField.ValueDisplayFormat = '%.3f';
            app.IntensityLastEditField.ValueChangedFcn = createCallbackFcn(app, @PGALastEditFieldValueChanged, true);
            app.IntensitySubdivisionEditFieldLabel = uilabel(app.RightPanel);
            app.IntensitySubdivisionEditFieldLabel.Text = 'Intensity Subdivision';
            app.IntensitySubdivisionEditField = uieditfield(app.RightPanel, 'numeric');
            app.IntensitySubdivisionEditField.ValueDisplayFormat = '%.3f';
            app.IntensitySubdivisionEditField.ValueChangedFcn = createCallbackFcn(app, @PGASubdivisionEditFieldValueChanged, true);
            app.ofSamplesforEachIntensityEditFieldLabel = uilabel(app.RightPanel);
            app.ofSamplesforEachIntensityEditFieldLabel.Text = '# of Samples for Each Intensity';
            app.ofSamplesforEachIntensityEditField = uieditfield(app.RightPanel, 'numeric');
            app.ofSamplesforEachIntensityEditField.ValueDisplayFormat = '%.0f';
            app.ofSamplesforEachIntensityEditField.ValueChangedFcn = createCallbackFcn(app, @ofSamplesforEachPGAEditFieldValueChanged, true);
            app.IntensityInitalEditFieldLabel = uilabel(app.RightPanel);
            app.IntensityInitalEditFieldLabel.Text = 'Intensity Inital';
            app.IntensityInitalEditField = uieditfield(app.RightPanel, 'numeric');
            app.IntensityInitalEditField.ValueDisplayFormat = '%.3f';
            app.IntensityInitalEditField.ValueChangedFcn = createCallbackFcn(app, @PGAInitalEditFieldValueChanged, true);
            app.SolverPanel = uipanel(app.RightPanel);
            app.SolverPanel.Title = 'Solver';
            app.OriginalDQFMButton = uibutton(app.SolverPanel, 'push');
            app.OriginalDQFMButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalDQFMButtonPushed, true);
            app.OriginalDQFMButton.Text = 'Original DQFM';
            app.ImprovedDQFMScalingButton = uibutton(app.SolverPanel, 'push');
            app.ImprovedDQFMScalingButton.ButtonPushedFcn = createCallbackFcn(app, @ImprovedDQFMScalingButtonPushed, true);
            app.ImprovedDQFMScalingButton.Text = 'Improved DQFM(Scaling)';
            app.BooleanAlgebraUMBButton = uibutton(app.SolverPanel, 'push');
            app.BooleanAlgebraUMBButton.ButtonPushedFcn = createCallbackFcn(app, @BooleanAlgebraUMBButtonPushed, true);
            app.BooleanAlgebraUMBButton.Text = 'Boolean Algebra (UMB)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EHRA_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end